classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        AplicaodeTelemetriaLabel  matlab.ui.control.Label
        EixoYListBoxLabel         matlab.ui.control.Label
        EixoYListBox              matlab.ui.control.ListBox
        VoltaSpinnerLabel         matlab.ui.control.Label
        VoltaSpinner              matlab.ui.control.Spinner
        TrackButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        lap              % Variável a qual fica salvo todos os dados importados e separados por voltas
        lapNum           % Salva o numero da volta sendo analisada no momento
        dataX            % Dados que serão plotados no eixo x 
        dataY            % Dados que serão plotados no eixo y 
        dataTypeY        % String com o nome do dado selecionado 
        cursorPoints     % Vector com os tempos dos pontos selecionados pelo utilizador com o rato
        interpolatedData
    end
    
    methods (Access = private)
        
        function plotData(app)
% Utilizamos um loop for para podermos imprimir multiplos dados no mesmo grafico            
             for ii = 1:length(app.dataTypeY)
                             
                    switch app.dataTypeY{ii}                                        
                        case 'Speed [Km/h]'
                            app.dataY = app.lap(app.lapNum).gps(:,3);
                            app.dataX = app.lap(app.lapNum).gps(:,4);
                        case 'GPS'
                            app.dataY = app.lap(app.lapNum).gps(:,1);
                            app.dataX = app.lap(app.lapNum).gps(:,2);
                        case 'Target id [A]'
                            app.dataY = app.lap(app.lapNum).controller.n1(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n1(:,5);
                        case 'Target iq [A]'
                            app.dataY = app.lap(app.lapNum).controller.n1(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n1(:,5);
                        case 'id [A]'
                            app.dataY = app.lap(app.lapNum).controller.n1(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n1(:,5);
                        case 'iq [A]'
                            app.dataY = app.lap(app.lapNum).controller.n1(:,4);
                            app.dataX = app.lap(app.lapNum).controller.n1(:,5);
                        case 'Ud [V]'
                            app.dataY = app.lap(app.lapNum).controller.n2(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n2(:,5);
                        case 'Uq [V]'
                            app.dataY = app.lap(app.lapNum).controller.n2(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n2(:,5);
                        case 'Voltage modulation'
                            app.dataY = app.lap(app.lapNum).controller.n2(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n2(:,5);
                        case 'AC Voltage [V]'
                            app.dataY = app.lap(app.lapNum).controller.n2(:,4);
                            app.dataX = app.lap(app.lapNum).controller.n2(:,5);
                        case 'AC Current [A]'
                            app.dataY = app.lap(app.lapNum).controller.n3(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n3(:,5);
                        case 'Max iq allowed [A]'
                            app.dataY = app.lap(app.lapNum).controller.n3(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n3(:,5);
                        case 'Motor Temperature [°C]'
                            app.dataY = app.lap(app.lapNum).controller.n3(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n3(:,5);
                        case 'Battery Voltage (Controller) [V]'
                            app.dataY = app.lap(app.lapNum).controller.n3(:,4);
                            app.dataX = app.lap(app.lapNum).controller.n3(:,5);
                        case 'Cap Voltage [V]'
                            app.dataY = app.lap(app.lapNum).controller.n4(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n4(:,5);
                        case 'Heatsink Temperature [°C]'
                            app.dataY = app.lap(app.lapNum).controller.n4(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n4(:,5);
                        case 'Battery Current (Controller) [A]'
                            app.dataY = app.lap(app.lapNum).controller.n4(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n4(:,5);
                        case 'Torque [N.m]'
                            app.dataY = app.lap(app.lapNum).controller.n4(:,4);
                            app.dataX = app.lap(app.lapNum).controller.n4(:,5);
                        case 'Engine RPM'
                            app.dataY = app.lap(app.lapNum).controller.n5(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n5(:,4); 
                        case 'Throttle Voltage [V]'
                            app.dataY = app.lap(app.lapNum).controller.n5(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n5(:,4);
                        case 'Throttle'
                            app.dataY = app.lap(app.lapNum).controller.n5(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n5(:,4);
                        case 'Battery Pack-1 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,1);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Battery Pack-2 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,2);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Battery Pack-3 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,3);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Battery Pack-4 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,4);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Battery Pack-5 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,5);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);                    
                        case 'Battery Assembly Voltage [V]'
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,6);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);    
                        case 'Battery maximum temperature [°C]'    
                             app.dataY = app.lap(app.lapNum).battery.assembly(:,7);
                             app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Pack-1 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);     
                        case 'Pack-1 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);                    
                        case 'Pack-1 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);
                        case 'Pack-1 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);
                        case 'Pack-1 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);
                        case 'Pack-2 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);     
                        case 'Pack-2 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);                    
                        case 'Pack-2 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);
                        case 'Pack-2 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);
                        case 'Pack-2 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);
                        case 'Pack-3 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);     
                        case 'Pack-3 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);                    
                        case 'Pack-3 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);
                        case 'Pack-3 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);
                        case 'Pack-3 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);
                        case 'Pack-4 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);     
                        case 'Pack-4 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);                    
                        case 'Pack-4 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);
                        case 'Pack-4 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);
                        case 'Pack-4 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);
                        case 'Pack-5 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);     
                        case 'Pack-5 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);                    
                        case 'Pack-5 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);
                        case 'Pack-5 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);
                        case 'Pack-5 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);
                        
                    end
                    
                    plot(app.UIAxes,app.dataX,app.dataY,'LineWidth',1.5); 
                    xlabel(app.UIAxes,'Tempo(s)'); 
                    app.UIAxes.FontWeight = 'bold';          
                    grid(app.UIAxes,'on');
                    grid(app.UIAxes,'minor');
                    app.UIAxes.GridAlpha = 0.075;
                    
                    hold(app.UIAxes,'on');
                    
                    
        %             No caso de dados em falta o gráfico anterior será apagado
                    if isempty(app.dataY)
                        plot(app.UIAxes,[0 0],[0 0]);
                    end
                                                                  
             end
             
                legend(app.UIAxes,app.dataTypeY);
                hold(app.UIAxes,'off')
           end  
        
        function  plotGPS(app)
            
            plot(app.UIAxes2,app.lap(app.lapNum).gps(:,2),app.lap(app.lapNum).gps(:,1));                          
            hold(app.UIAxes2,'on');
            set(app.UIAxes2,'XTick',[], 'YTick', []);
            xlabel(app.UIAxes2,'');
            ylabel(app.UIAxes2,'');
            title(app.UIAxes2,'GPS')

            cursorGPSpoints(app);
            plot(app.UIAxes2,app.interpolatedData(:,1),app.interpolatedData(:,2),"o","LineWidth",1.5);
            
            hold(app.UIAxes2,'off');


        end
        
        function cursorGPSpoints(app)
% Recebe os dados da seleção com o Rato feita pelo utilizador e realiza 2
% interpolações para obter os valores da long e lat correspondentes

            lat = app.lap(app.lapNum).gps(:,1);
            long = app.lap(app.lapNum).gps(:,2);
            gpsTime = app.lap(app.lapNum).gps(:,4);
            app.interpolatedData = zeros(length(app.cursorPoints),2);

            for ii = 1:length(app.cursorPoints)
                app.interpolatedData(ii,1) = interp1(gpsTime,long,app.cursorPoints(ii));               
            end
            for ii = 1:length(app.cursorPoints)
                app.interpolatedData(ii,2) = interp1(gpsTime,lat,app.cursorPoints(ii));               
            end
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          % Carregamento dos dados
            load KIRO_4_Processado.mat;
            app.lap = data;                    
           % Spinner seleção volta    
            app.lapNum = app.VoltaSpinner.Value;           
            app.VoltaSpinner.Limits = [1,length(app.lap)];
           % List box eixo y 
            app.dataTypeY = app.EixoYListBox.Value;
                            
            plotData(app);
            plotGPS(app);

            
        end

        % Value changed function: EixoYListBox
        function EixoYListBoxValueChanged(app, event)
% app.dataTypeY eh um string array uma vez que podem ser feitas
% multiplas selecoesde dados
            app.dataTypeY = app.EixoYListBox.Value;
            plotData(app);
            
        end

        % Value changed function: VoltaSpinner
        function VoltaSpinnerValueChanged(app, event)
            app.lapNum = app.VoltaSpinner.Value;
            plotData(app);
            plotGPS(app);
            
        end

        % Button pushed function: TrackButton
        function TrackButtonPushed(app, event)
% Funcao responsavel pela obtencao do ponto 'clicado' pelo utilizador 
            
% Esta função invoca o data cursor mode e cria um dcm( data cursor management). 
% Do dcm conseguimos tirar dados do cursor que em seguida são retiradas
% apenas os tempos para a realização da correspondência com o GPS.

            datacursormode(app.UIFigure,'on');
            dcm = datacursormode(app.UIFigure);
            aux = getCursorInfo(dcm);

        % Por algum motivo desconhecido não foi possivel usar vetorização.
        % Para contornar isto foi utilizado um loop for

            app.cursorPoints = zeros(length(aux),1);  % Necessário para exclusão de pontos
            
            for i = 1:length(aux)
                app.cursorPoints(i) = aux(i).Position(1);
            end

            datacursormode(app.UIFigure,'off');
            plotGPS(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Kártrodrómo internacional de Kiro'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.AplicaodeTelemetriaLabel = uilabel(app.UIFigure);
            app.AplicaodeTelemetriaLabel.Text = 'Aplicação de Telemetria';
            app.EixoYListBoxLabel = uilabel(app.UIFigure);
            app.EixoYListBoxLabel.Text = 'Eixo Y';
            app.EixoYListBox = uilistbox(app.UIFigure);
            app.EixoYListBox.Items = {'Torque [N.m]', 'Battery Assembly Voltage [V]', 'GPS', 'Speed [Km/h]', 'Target id [A]', 'Target iq [A]', 'id [A]', 'iq [A]', 'Ud [V]', 'Uq [V]', 'Voltage modulation', 'AC Voltage [V]', 'AC Current [A]', 'Max iq allowed [A]', 'Motor Temperature [°C]', 'Battery Voltage (Controller) [V]', 'Battery Current (Controller) [A]', 'Cap Voltage [V]', 'Heatsink Temperature [°C]', 'Engine RPM', 'Throttle Voltage [V]', 'Throttle', 'Battery Pack-1 Voltage [V]', 'Battery Pack-2 Voltage [V]', 'Battery Pack-3 Voltage [V]', 'Battery Pack-4 Voltage [V]', 'Battery Pack-5 Voltage [V]', 'Battery maximum temperature [°C]', 'Pack-1 V1 [V]', 'Pack-1 V2 [V]', 'Pack-1 V3 [V]', 'Pack-1 V4 [V]', 'Pack-1 V5 [V]', 'Pack-1 V6 [V]', 'Pack-2 V1 [V]', 'Pack-2 V2 [V]', 'Pack-2 V3 [V]', 'Pack-2 V4 [V]', 'Pack-2 V5 [V]', 'Pack-3 V1 [V]', 'Pack-3 V2 [V]', 'Pack-3 V3 [V]', 'Pack-3 V4 [V]', 'Pack-3 V5 [V]', 'Pack-4 V1 [V]', 'Pack-4 V2 [V]', 'Pack-4 V3 [V]', 'Pack-4 V5 [V]', 'Pack-5 V1 [V]', 'Pack-5 V2 [V]', 'Pack-5 V3 [V]', 'Pack-5 V4 [V]', 'Pack-5 V5 [V]'};
            app.EixoYListBox.Multiselect = 'on';
            app.EixoYListBox.ValueChangedFcn = createCallbackFcn(app, @EixoYListBoxValueChanged, true);
            app.EixoYListBox.Value = {'Torque [N.m]'};
            app.VoltaSpinnerLabel = uilabel(app.UIFigure);
            app.VoltaSpinnerLabel.Text = 'Volta';
            app.VoltaSpinner = uispinner(app.UIFigure);
            app.VoltaSpinner.RoundFractionalValues = 'on';
            app.VoltaSpinner.ValueChangedFcn = createCallbackFcn(app, @VoltaSpinnerValueChanged, true);
            app.VoltaSpinner.Value = 1;
            app.TrackButton = uibutton(app.UIFigure, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @TrackButtonPushed, true);
            app.TrackButton.Text = 'Track';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end